classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        Button_3    matlab.ui.control.Button
        Button_2    matlab.ui.control.Button
        Button      matlab.ui.control.Button
        RightPanel  matlab.ui.container.Panel
        Label_2     matlab.ui.control.Label
        Label       matlab.ui.control.Label
        UIAxes2     matlab.ui.control.UIAxes
        UIAxes      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        cam % Description
        frame
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.cam=webcam(1);
            app.frame=snapshot(app.cam);
            im=image(app.UIAxes,zeros(size(app.frame)),'CDataMapping',"direct");
            axis(app.UIAxes,'image');
            preview(app.cam,im);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.frame=snapshot(app.cam);
            imshow(app.frame,'Parent',app.UIAxes2);
            %保存图片
            global numofshot
            numofshot=1;
            str=['D:\A.app\capture\' num2str(numofshot),'.jpg'];
            imwrite(app.frame,str,'jpg');
            numofshot=numofshot+1;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            delete(app.UIFigure);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {555, 555};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '预览';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '捕捉';
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '退出';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes2 = uiaxes(app.RightPanel);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '预览画面';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '捕捉画面';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end